classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        RangexEditFieldLabel     matlab.ui.control.Label
        RangexEditField          matlab.ui.control.NumericEditField
        SizexSpinnerLabel        matlab.ui.control.Label
        SizexSpinner             matlab.ui.control.Spinner
        SizeySpinnerLabel        matlab.ui.control.Label
        SizeySpinner             matlab.ui.control.Spinner
        SimulationSettingsLabel  matlab.ui.control.Label
        CovarianceSettingsLabel  matlab.ui.control.Label
        ModelDropDownLabel       matlab.ui.control.Label
        ModelDropDown            matlab.ui.control.DropDown
        RangeyEditFieldLabel     matlab.ui.control.Label
        RangeyEditField          matlab.ui.control.NumericEditField
        AzimuthEditFieldLabel    matlab.ui.control.Label
        AzimuthEditField         matlab.ui.control.NumericEditField
        VarianceEditFieldLabel   matlab.ui.control.Label
        VarianceEditField        matlab.ui.control.NumericEditField
        AlphaEditFieldLabel      matlab.ui.control.Label
        AlphaEditField           matlab.ui.control.NumericEditField
        RunButton                matlab.ui.control.Button
        TolerenceEditFieldLabel  matlab.ui.control.Label
        TolerenceEditField       matlab.ui.control.NumericEditField
        SeedButtonGroup          matlab.ui.container.ButtonGroup
        defaultButton            matlab.ui.control.RadioButton
        shuffleButton            matlab.ui.control.RadioButton
        valueButton              matlab.ui.control.RadioButton
        EditField                matlab.ui.control.NumericEditField
    end

    methods (Access = private)

        % Button pushed function: RunButton
        function RunButtonPushed(app, event)
            % Define the simulation parameters
            sim.s=[app.SizexSpinner.Value app.SizeySpinner.Value]; % simulation size 
            sim.n=1; % number of simulation
            sim.tol=app.TolerenceEditField.Value;
            sim.DisplayProgression=true;
            sim.seed = app.SeedButtonGroup.SelectedObject.Text;
            if strcmp(sim.seed,'value: ')
                sim.seed = app.EditField.Value;
            end
            
            % Define the covariance parameters
            covar.model = app.ModelDropDown.Value; % type of covariance function, see covarIni.m for option
            covar.range = [app.RangexEditField.Value app.RangeyEditField.Value]; % range of covariance, vector same size as sim.s.
            covar.azimuth = app.AzimuthEditField.Value; % orientation of the covariance
            covar.var = app.VarianceEditField.Value; % variance
            covar.alpha = app.AlphaEditField.Value; % parameter of covariance function (depend on the model chosen, see covarIni.m for option)
            
            % Compute
            res = FGS(sim,covar);
            
            imagesc(app.UIAxes, res{1}); axis(app.UIAxes,'equal','tight');
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.UIAxes = uiaxes(app.UIFigure);
            app.RangexEditFieldLabel = uilabel(app.UIFigure);
            app.RangexEditFieldLabel.Text = 'Range x';
            app.RangexEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangexEditField.Limits = [0 Inf];
            app.RangexEditField.Value = 10;
            app.SizexSpinnerLabel = uilabel(app.UIFigure);
            app.SizexSpinnerLabel.Text = 'Size x';
            app.SizexSpinner = uispinner(app.UIFigure);
            app.SizexSpinner.Limits = [0 1000];
            app.SizexSpinner.Value = 100;
            app.SizeySpinnerLabel = uilabel(app.UIFigure);
            app.SizeySpinnerLabel.Text = 'Size y';
            app.SizeySpinner = uispinner(app.UIFigure);
            app.SizeySpinner.Limits = [0 1000];
            app.SizeySpinner.Value = 100;
            app.SimulationSettingsLabel = uilabel(app.UIFigure);
            app.SimulationSettingsLabel.Text = 'Simulation Settings';
            app.CovarianceSettingsLabel = uilabel(app.UIFigure);
            app.CovarianceSettingsLabel.Text = 'Covariance Settings';
            app.ModelDropDownLabel = uilabel(app.UIFigure);
            app.ModelDropDownLabel.Text = 'Model';
            app.ModelDropDown = uidropdown(app.UIFigure);
            app.ModelDropDown.Items = {'nugget', 'triangle', 'cosine', 'circular', 'spherical', 'cubic', 'hyperbolic', 'exponential', 'gaussian', 'stable', 'power', 'k-bessel', 'logarithmic', 'cauchy', 'cardinal sine', 'matheron'};
            app.ModelDropDown.Value = 'k-bessel';
            app.RangeyEditFieldLabel = uilabel(app.UIFigure);
            app.RangeyEditFieldLabel.Text = 'Range y';
            app.RangeyEditField = uieditfield(app.UIFigure, 'numeric');
            app.RangeyEditField.Limits = [0 Inf];
            app.RangeyEditField.Value = 10;
            app.AzimuthEditFieldLabel = uilabel(app.UIFigure);
            app.AzimuthEditFieldLabel.Text = 'Azimuth';
            app.AzimuthEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarianceEditFieldLabel = uilabel(app.UIFigure);
            app.VarianceEditFieldLabel.Text = 'Variance';
            app.VarianceEditField = uieditfield(app.UIFigure, 'numeric');
            app.VarianceEditField.Limits = [0 Inf];
            app.VarianceEditField.Value = 1;
            app.AlphaEditFieldLabel = uilabel(app.UIFigure);
            app.AlphaEditFieldLabel.Text = 'Alpha';
            app.AlphaEditField = uieditfield(app.UIFigure, 'numeric');
            app.AlphaEditField.Limits = [0 Inf];
            app.AlphaEditField.Value = 5;
            app.RunButton = uibutton(app.UIFigure, 'push');
            app.RunButton.ButtonPushedFcn = createCallbackFcn(app, @RunButtonPushed, true);
            app.RunButton.Text = 'Run';
            app.TolerenceEditFieldLabel = uilabel(app.UIFigure);
            app.TolerenceEditFieldLabel.Text = 'Tolerence';
            app.TolerenceEditField = uieditfield(app.UIFigure, 'numeric');
            app.TolerenceEditField.Limits = [0 1];
            app.TolerenceEditField.Value = 1e-06;
            app.SeedButtonGroup = uibuttongroup(app.UIFigure);
            app.SeedButtonGroup.Title = 'Seed';
            app.defaultButton = uiradiobutton(app.SeedButtonGroup);
            app.defaultButton.Text = 'default';
            app.shuffleButton = uiradiobutton(app.SeedButtonGroup);
            app.shuffleButton.Text = 'shuffle';
            app.shuffleButton.Value = true;
            app.valueButton = uiradiobutton(app.SeedButtonGroup);
            app.valueButton.Text = 'value: ';
            app.EditField = uieditfield(app.SeedButtonGroup, 'numeric');
            app.EditField.Limits = [0 Inf];
        end
    end

    methods (Access = public)

        % Construct app
        function app = app

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end